classdef FHMSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        dt10xSpinner                   matlab.ui.control.Spinner
        dt10xSpinnerLabel              matlab.ui.control.Label
        ForcingFuntiontEditFieldLabel  matlab.ui.control.Label
        FinalTimeSpinner               matlab.ui.control.Spinner
        ForcingFuntiontEditField       matlab.ui.control.EditField
        FinalTimeSpinnerLabel          matlab.ui.control.Label
        InitialVelocitySpinner         matlab.ui.control.Spinner
        InitialVelocitySpinnerLabel    matlab.ui.control.Label
        InitialPositionSpinner         matlab.ui.control.Spinner
        InitialPositionSpinnerLabel    matlab.ui.control.Label
        ClearButton                    matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        SpringConstantNmSpinner        matlab.ui.control.Spinner
        SpringConstantNmSpinnerLabel   matlab.ui.control.Label
        DampingCoeffiientkgsSpinner    matlab.ui.control.Spinner
        DampingCoeffiientkgsSpinnerLabel  matlab.ui.control.Label
        MasskgSpinner                  matlab.ui.control.Spinner
        MasskgSpinnerLabel             matlab.ui.control.Label
        AnimationPlot                  matlab.ui.control.UIAxes
        MotionPlot                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            m = app.MasskgSpinner.Value;
            b = app.DampingCoeffiientkgsSpinner.Value;
            k = app.SpringConstantNmSpinner.Value;
            if app.ForcingFuntiontEditField.Value, F = str2func("@(t)" + app.ForcingFuntiontEditField.Value);
            else, F = @(t) 0;
            end
            y0 = app.InitialPositionSpinner.Value;
            v0 = app.InitialVelocitySpinner.Value;
            tf = app.FinalTimeSpinner.Value;
            dt = 10^(app.dt10xSpinner.Value);
            oscillator = FHO(m,b,k,F, y0,v0, 0,tf,dt);

            yy = oscillator.getMotion();
            xx = 0:dt:tf;

            plt = app.MotionPlot;
            plot(plt, xx, yy);
            xlim(plt, [0 tf]); ylim(plt, [-ceil(max(abs(yy)))-1, ceil(max(abs(yy)))+1]);
            xticks(plt, 0:tf); yticks(plt, -ceil(max(abs(yy)))-1:ceil(max(abs(yy)))+1);
            
            anim = app.AnimationPlot;
            anchor = min(yy)-1;
            xlim(anim, [anchor+.5 max(yy)+1]);
            xticks(anim, [min(yy) 0 max(yy)]);
            h = plot(anim, [anchor-1 y0], [0 0], 'b-o', 'LineWidth',1, ...
                'MarkerFaceColor','b', 'MarkerSize',12);
            o = animatedline(plt, 'Marker','o', 'MarkerFaceColor','b');
            for t = 1:length(yy)
                h.XData = [anchor-1 yy(t)];
                o.clearpoints();
                o.addpoints(xx(t), yy(t));
                drawnow limitrate;
                pause(dt);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MotionPlot = uiaxes(app.GridLayout);
            title(app.MotionPlot, 'Motion')
            app.MotionPlot.XTick = [];
            app.MotionPlot.YTick = [];
            app.MotionPlot.ZTick = [];
            app.AnimationPlot = uiaxes(app.GridLayout);
            title(app.AnimationPlot, 'Animation')
            zlabel(app.AnimationPlot, 'Z')
            app.AnimationPlot.XTick = [];
            app.AnimationPlot.YTick = [];
            app.MasskgSpinnerLabel = uilabel(app.GridLayout);
            app.MasskgSpinnerLabel.Text = 'Mass (kg)';
            app.MasskgSpinner = uispinner(app.GridLayout);
            app.MasskgSpinner.Step = 0.01;
            app.MasskgSpinner.Limits = [0 Inf];
            app.DampingCoeffiientkgsSpinnerLabel = uilabel(app.GridLayout);
            app.DampingCoeffiientkgsSpinnerLabel.Text = {'Damping '; 'Coeffiient (kg/s)'};
            app.DampingCoeffiientkgsSpinner = uispinner(app.GridLayout);
            app.DampingCoeffiientkgsSpinner.Step = 0.01;
            app.DampingCoeffiientkgsSpinner.Limits = [0 Inf];
            app.SpringConstantNmSpinnerLabel = uilabel(app.GridLayout);
            app.SpringConstantNmSpinnerLabel.Text = {'Spring '; 'Constant (N/m)'};
            app.SpringConstantNmSpinner = uispinner(app.GridLayout);
            app.SpringConstantNmSpinner.Step = 0.01;
            app.SpringConstantNmSpinner.Limits = [0 Inf];
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.Text = 'Clear';
            app.InitialPositionSpinnerLabel = uilabel(app.GridLayout);
            app.InitialPositionSpinnerLabel.Text = 'Initial Position';
            app.InitialPositionSpinner = uispinner(app.GridLayout);
            app.InitialPositionSpinner.Step = 0.01;
            app.InitialVelocitySpinnerLabel = uilabel(app.GridLayout);
            app.InitialVelocitySpinnerLabel.Text = 'Initial Velocity';
            app.InitialVelocitySpinner = uispinner(app.GridLayout);
            app.InitialVelocitySpinner.Step = 0.01;
            app.FinalTimeSpinnerLabel = uilabel(app.GridLayout);
            app.FinalTimeSpinnerLabel.Text = 'Final Time';
            app.ForcingFuntiontEditField = uieditfield(app.GridLayout, 'text');
            app.ForcingFuntiontEditField.Placeholder = 'sin(x)';
            app.FinalTimeSpinner = uispinner(app.GridLayout);
            app.FinalTimeSpinner.Step = 0.01;
            app.FinalTimeSpinner.Limits = [0 Inf];
            app.FinalTimeSpinner.Value = 10;
            app.ForcingFuntiontEditFieldLabel = uilabel(app.GridLayout);
            app.ForcingFuntiontEditFieldLabel.Text = 'Forcing Funtion (t)';
            app.dt10xSpinnerLabel = uilabel(app.GridLayout);
            app.dt10xSpinnerLabel.Text = 'dt (10^x)';
            app.dt10xSpinner = uispinner(app.GridLayout);
            app.dt10xSpinner.Step = 0.5;
            app.dt10xSpinner.Limits = [-10 0];
            app.dt10xSpinner.Value = -2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FHMSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end